classdef controlTemperatura < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        StatusLabel_A                   matlab.ui.control.Label
        ActualizarButton                matlab.ui.control.Button
        IntervalodemuestreomsEditField  matlab.ui.control.NumericEditField
        IntervalodemuestreosLabel       matlab.ui.control.Label
        HistresisCEditField             matlab.ui.control.NumericEditField
        HistresisCEditFieldLabel        matlab.ui.control.Label
        EscalonButton                   matlab.ui.control.Button
        SetpointCEditField              matlab.ui.control.NumericEditField
        SetpointCLabel                  matlab.ui.control.Label
        ConfiguracinPanel               matlab.ui.container.Panel
        DesconectarButton               matlab.ui.control.Button
        PORTDropDown                    matlab.ui.control.DropDown
        PORTDropDownLabel               matlab.ui.control.Label
        BaudRateDropDown                matlab.ui.control.DropDown
        BaudRateDropDownLabel           matlab.ui.control.Label
        ConectarButton                  matlab.ui.control.Button
        TemperaturaGauge                matlab.ui.control.LinearGauge
        TemperaturaGaugeLabel           matlab.ui.control.Label
        EstadodeconexionLamp            matlab.ui.control.Lamp
        EstadodeconexionLampLabel       matlab.ui.control.Label
        EstadodelcalefactorLamp         matlab.ui.control.Lamp
        EstadodelcalefactorLampLabel    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        conexionSerial % Conexion de serie
        tempData        % guarda temperatura
        timeData        % guarda tiempo 
        setPoint
        histeresis
        intervalo

    end
    
    methods (Access = public)

        function lectura_DataSerial(app, obj, event)
            % Leer la línea de datos crudos del puerto serie
            inputLine = readline(obj); 
            disp(inputLine);       % Mostrar los datos crudos recibidos
            
            eventtime = datetime(evt.AbsTime,Format='yyyy-MM-dd''T''HH:mm:ss.SSS');
            latestdata = processData(app,inputline,eventtime);

            if app.Logging
                logData(app,inputLine,latestdata);
            end


            % Verificar si los datos son válidos
            if  ~any(isnan(latestdata))

                %% Tipo de informacion que recibo
                if latestdata(end) == 'T'
                    temperatura = latestdata(1:end-1);

                elseif latestdata(end) == 'C'
                    estadoCalefactor = latestdata(1:end-1);

                else
                    error("Error al leer informacion");
                end

                %% Actualizar el estado del calefactor
                if estadoCalefactor == 1
                    app.EstadodelcalefactorLamp.Color = [0.39,0.83,0.07]; % Verde
                else
                    app.EstadodelcalefactorLamp.Color = [0.78, 0.1, 0.1]; %rojo
                end

                %% Actualizar el valor de temperatura
                app.TemperaturaGauge.Value = temperatura;

                %% Veo si existen las variables, si no existen las crea
                if isempty(app.tempData) || isempty(app.timeData)
                    app.tempData    = [];
                    app.timeData    = [];
                    app.setPoint    = [];
                end

                %% Actualizar datos históricos
                app.tempData(end + 1) = temperatura;
                app.timeData(end + 1) = datetime('now');
                app.setPoint(end + 1) = app.setPoint;

                % Actualizar gráficos
                cla(app.UIAxes);
                plot(app.UIAxes, app.tempData.time, app.tempData.temp, "blue"); % Temperatura
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, app.tempData.time, app.tempData.spHistorico, "black--"); % Setpoint

                % Ajustar límites del eje
                app.UIAxes.XLim = [datetime('now') - minutes(0.5), datetime('now')]; % ultimos 30 segundos
                app.UIAxes.YLim = [0, 85];
                legend(app.UIAxes, {'DS1820', 'Setpoint'});

                % Dibujar el gráfico
                drawnow; %app.UIAxes
            else
                disp('Error: Datos malformados o incompletos.');
            end
        end

        function setAppState(app,state)
            switch state
                case "Seleccion dispositivo"
                    app.BaudRateDropDown.Enable  = "on";
                    app.PORTDropDown.Enable      = "on";
                    app.DesconectarButton.Enable = "off";
                    app.ConectarButton.Enable    = "on";
                    app.DesconectarButton.Enable = "off";
                    app.StatusLabel_A.Visible    = "off";
                    app.ActualizarButton.Enable  = "off";
                case "Conectado"
                    app.EstadodeconexionLamp.Color = [0.39,0.83,0.07]; % Verde;
                    app.BaudRateDropDown.Enable  = "off";
                    app.PORTDropDown.Enable      = "off";
                    app.ActualizarButton.Enable  = "on";
                    app.DesconectarButton.Enable = "on";
                    app.ConectarButton.Enable    = "off";
                case "Desconectado"
                    app.EstadodeconexionLamp.Color = [0.78, 0.1, 0.1]; %rojo
                    app.ActualizarButton.Enable = "off";
                    app.ConectarButton.Enable = "on";
                    app.DesconectarButton.Enable = "off";
                    app.PORTDropDown.Enable = "on";
                    app.BaudRateDropDown.Enable = "on";
                case "Error conectado"
                    app.ConectarButton.Enable = "off";
                    pause(2);
                    app.EstadodeconexionLamp.Color = [0.78, 0.1, 0.1]; %rojo
                    disp("Error conectando a la placa");
                    app.BaudRateDropDown.Enable  = "on";
                    app.PORTDropDown.Enable      = "on";
                    app.ActualizarButton.Enable  = "off";
                    app.DesconectarButton.Enable = "off";
                    app.ConectarButton.Enable    = "on";
                case "Actualizando"
                    % Actualizar el estado en la GUI
                    app.StatusLabel_A.Visible = "on";
                    app.ActualizarButton.Enable = "off";
                    app.StatusLabel_A.Text = "Parámetros enviados exitosamente";
                    pause(1);
                    app.StatusLabel_A.Visible = "off";
                    app.ActualizarButton.Enable = "on";
                case "Error actualizado"
                    % Manejo de errores y actualizar el estado en la GUI
                    app.StatusLabel_A.Visible = "on";
                    app.ActualizarButton.Enable = "off";
                    app.StatusLabel_A.Text = "Error enviando parámetros";
                    pause(1);
                    app.StatusLabel_A.Visible = "off";
                    app.ActualizarButton.Enable = "on";
                case "Cerrar sesion"
                    app.EstadodeconexionLamp.Color    = [0.78, 0.1, 0.1]; %rojo
                    app.EstadodelcalefactorLamp.Color = [0.78, 0.1, 0.1]; %rojo
                    app.ActualizarButton.Enable       = "off";
                    delete(app)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Funcion que se ejecuta cuando se inicializa la aplicacion
            app.PORTDropDown.Items = serialportlist("available");
            app.BaudRateDropDown.Items = {'1200', '2400','4800','9600','19200', '38400'};
            app.UIFigure.Resize = 'off';

            setAppState(app,"Seleccion dispositivo");
            %Lee valores al inciar programa
            app.setPoint   = app.SetpointCEditField.Value;
            app.histeresis = app.HistresisCEditField.Value;
            app.intervalo  = app.IntervalodemuestreomsEditField.Value;
         
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            portChoice     = app.PORTDropDown.Value;
            baudRateChoice = app.BaudRateDropDown.Value;

            try
                app.conexionSerial = serialport(portChoice,str2double(baudRateChoice));
                configureTerminator(app.conexionSerial,"CR");
                % app.conexionSerial.Timeout = 10; % Establecer el tiempo de espera para operaciones
                % Lectura de datos seriales
                configureCallback(app.conexionSerial,"terminator",@(obj, event) lectura_DataSerial(app,obj,event));
                
               
                % Manda mensaje al micro de que se conecto a funcion: check_comm()
                %message = num_to_ascii(app,1); % Mando un 1 para establecer conexion 
                write(app.conexionSerial,1,"uint8"); %Manda la info en ASCII

                setAppState(app,"Conectado");

                %% Se cargan los valores iniciales de la App
                app.setPoint   = round(app.SetpointCEditField.Value);
                app.histeresis = round(app.HistresisCEditField.Value);
                app.intervalo  = round(app.IntervalodemuestreomsEditField.Value);

                write(app.conexionSerial,app.setPoint,"uint8");
                write(app.conexionSerial,app.histeresis,"uint8");
                write(app.conexionSerial,app.intervalo,"uint16");

            catch ME
                setAppState(app,"Error conectado");
            end
        
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.setPoint   = round(app.SetpointCEditField.Value);
            app.histeresis = round(app.HistresisCEditField.Value);
            app.intervalo  = round(app.IntervalodemuestreomsEditField.Value);

            %% Verifico que este conectado
            if isempty(app.conexionSerial) || ~isvalid(app.conexionSerial)
                return;
            end

            %% Construir el mensaje a enviar
            try
                % Enviar el mensaje a través de la conexión serial
                write(app.conexionSerial,app.setPoint,"uint8");
                write(app.conexionSerial,app.histeresis,"uint8");
                write(app.conexionSerial,app.intervalo,"uint16");

                setAppState(app,"Actualizando")  % Actualizar el estado en la GUI
            catch ME
                disp(ME.message);
                setAppState(app,"Error actualizado");

            end
        end

        % Button pushed function: EscalonButton
        function escalonButton(app, event)
            app.setPoint = app.setPoint + 10;  %incremento del setpoint
            app.SetpointCEditField.Value = app.setPoint; % actualiza el setpoint en la GUI
            ActualizarButtonPushed(app, event); %envia data al micro

        end

        % Close request function: UIFigure
        function UI_close(app, event)
            if ~isempty(app.conexionSerial)
                % Cerrar el puerto serie
                clear app.conexionSerial;
            end
            setAppState(app,"Cerrar sesion");
            
        end

        % Callback function
        function activarDarkMode(app, event)
            % No puedo borrar la funcion
        end

        % Button pushed function: DesconectarButton
        function desconectarButtonPushed(app, event)
            
            % HAY QUE MANDAR SEÑAL DE Q SE DESCONECTO PARA VOLVER A LLAMAR
            % A APPINIT()

            % Manda mensaje al micro de que se conecto a funcion: check_comm()
            % message = num_to_ascii(app,2); % Mando un 1 para establecer conexion
            % write(app.conexionSerial,message,"char"); %Manda la info en ASCII

            flush(app.conexionSerial);
            delete(app.conexionSerial);
            setAppState(app,"Desconectado");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UI_close, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'tiempo vs. temperatura')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Temperatura [C°]')
            zlabel(app.UIAxes, 'Z')
            app.EstadodelcalefactorLampLabel = uilabel(app.UIFigure);
            app.EstadodelcalefactorLampLabel.Text = 'Estado del calefactor';
            app.EstadodelcalefactorLamp = uilamp(app.UIFigure);
            app.EstadodeconexionLampLabel = uilabel(app.UIFigure);
            app.EstadodeconexionLampLabel.Text = 'Estado de conexion';
            app.EstadodeconexionLamp = uilamp(app.UIFigure);
            app.TemperaturaGaugeLabel = uilabel(app.UIFigure);
            app.TemperaturaGaugeLabel.Text = 'Temperatura';
            app.TemperaturaGauge = uigauge(app.UIFigure, 'linear');
            app.ConfiguracinPanel = uipanel(app.UIFigure);
            app.ConfiguracinPanel.Title = 'Configuración';
            app.ConectarButton = uibutton(app.ConfiguracinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.BaudRateDropDownLabel = uilabel(app.ConfiguracinPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.ConfiguracinPanel);
            app.BaudRateDropDown.Items = {''};
            app.BaudRateDropDown.Value = '';
            app.PORTDropDownLabel = uilabel(app.ConfiguracinPanel);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.ConfiguracinPanel);
            app.PORTDropDown.Items = {''};
            app.PORTDropDown.Value = '';
            app.DesconectarButton = uibutton(app.ConfiguracinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @desconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.SetpointCLabel = uilabel(app.Panel);
            app.SetpointCLabel.Text = 'Set-point [°C]';
            app.SetpointCEditField = uieditfield(app.Panel, 'numeric');
            app.SetpointCEditField.Limits = [0 100];
            app.SetpointCEditField.Value = 20;
            app.EscalonButton = uibutton(app.Panel, 'push');
            app.EscalonButton.ButtonPushedFcn = createCallbackFcn(app, @escalonButton, true);
            app.EscalonButton.Text = 'Escalon';
            app.HistresisCEditFieldLabel = uilabel(app.Panel);
            app.HistresisCEditFieldLabel.Text = 'Histéresis [°C]';
            app.HistresisCEditField = uieditfield(app.Panel, 'numeric');
            app.HistresisCEditField.Value = 3;
            app.IntervalodemuestreosLabel = uilabel(app.Panel);
            app.IntervalodemuestreosLabel.Text = {'Intervalo de '; 'muestreo [ms]'};
            app.IntervalodemuestreomsEditField = uieditfield(app.Panel, 'numeric');
            app.IntervalodemuestreomsEditField.Value = 3000;
            app.ActualizarButton = uibutton(app.Panel, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.StatusLabel_A = uilabel(app.Panel);
            app.StatusLabel_A.Text = 'StatusLabel_A';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlTemperatura

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end